classdef Audiogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudiogramApp       matlab.ui.Figure
        EditFieldScore     matlab.ui.control.NumericEditField
        WynikdBLabel       matlab.ui.control.Label
        EditFieldRightEar  matlab.ui.control.NumericEditField
        PraweuchodBLabel   matlab.ui.control.Label
        EditFieldLeftEar   matlab.ui.control.NumericEditField
        LeweuchodBLabel    matlab.ui.control.Label
        ButtonRozpocznij   matlab.ui.control.Button
        ButtonSlysze       matlab.ui.control.Button
        ButtonPorownaj     matlab.ui.control.Button
        ButtonPrevious     matlab.ui.control.Button
        ButtonNext         matlab.ui.control.Button
        ImageCelebrity     matlab.ui.control.Image
        AxesAudiogram      matlab.ui.control.UIAxes
    end


    properties (Access = public)

        f_audiogram = [125 500 1000 2000 3000 4000 6000 8000 100000]; % 9 czestotliwosci audiogramu

        coef_audiogram = [1]; % wspolczynniki (wzmacniajace n*10dB); od 0dB do +140dB; Beda wypelnione po starcie programu

        offset = 0; % sztywno ustawiany Offset uszkodzenia sluchu, np. 7dB (bo 0dB na 1kHz jest glupie)

        left_ear = []; % ubytki sluchu w lewym uchu (w dB) dla 9 czestotliwosci audiogramu
        right_ear = []; % ubytki sluchu w prawym uchu (w dB) dla 9 czestotliwosci audiogramu
        left_ear_score = 0; % wynik audi0ogramu w lewym uchu [dB]
        right_ear_score = 0; % wynik audiogramu w lewym uchu [dB]
        final_score = 0; % wynik audiogramu uogolniony
        time_audio = 5; % czaas odtwarzania w sekundach

        % Uniwersalny generator
        A = 0.01; % stale wzmocnienie (zeby nie ogluchnac przy testach); domyslnie bylo 1
        f = 0.0; % czestotliwosc generowanego dzwieku [Hz]
        fs = 48000; % czestotliwosc probkowania [Hz]
        t = 0.0; % probki czasowe 1 sekundy

        y = [];

        flaga = 1;

        ii = 1;

    end


    methods (Access = public)

        function [] = leftTest(app)
            %clear sound;
            for i = 1:9
                ff = app.f_audiogram(i) % czestotliwosc generowanego dzwieku [Hz]
                tt = 0:1/app.fs:1;

                while app.ii < 9 % powinno byc 14
                    app.left_ear(i) = (app.ii-1)*10; % ubytek w dB
                    
                    %             gain = 0; % wzmocnienie dB; Do wzmacniania kolejnych
                    coeff = app.coef_audiogram(app.ii) % wspolczynnik (zwiekszajacy +dB)
                    app.y=(coeff*(app.A)*(sin(2*pi*ff*tt)));  % sygnal harmoniczny
                    left=app.y(1,1:48000);
                    noise = app.A.*rand(1, 48000); % Czy tego szumu nie przyciszyc?
                    left = [left; noise];

                    % generowanie 10 sekund w lewym kanale
                    left_10sec = [];
                    for k = 1:2
                        left_10sec = [left_10sec left];
                    end

                    % sound(left_10sec, app.fs);

                    q = audioplayer(left_10sec, app.fs);
                    playblocking(q, app.fs);

                    app.ii = app.ii + 1;

                    
                end

                app.ii = 1;

                %                 q = audioplayer(left_10sec, app.fs);
                %                 playblocking(q, app.fs);

            end
            
            % wynik audiogramu w lewym uchu [dB]
            % 500 Hz - element #2, 1000 Hz - #3, 2000 Hz - #4, 4000 Hz - #6
            if (abs(app.left_ear(2) - app.left_ear(4)) > 40)
                if (app.left_ear(4) < app.left_ear(6)) % Wyjatek nr 2
                    app.left_ear_score = (app.left_ear(2) + app.left_ear(3) + app.left_ear(4) + app.left_ear(6)) / 4.0;
                else % Wyjatek nr 1
                    app.left_ear_score = (app.left_ear(2) + app.left_ear(3) + app.left_ear(6)) / 3.0;
                end
            else % Przypadek typowy
                app.left_ear_score = (app.left_ear(2) + app.left_ear(3) + app.left_ear(4)) / 3.0
            end
            
            p = plot(app.AxesAudiogram, app.f_audiogram, app.left_ear);
            p.Color = 'blue';
            hold(app.AxesAudiogram, 'on');
            app.EditFieldLeftEar.Value = app.left_ear_score;
            
        end

        function [] = rightTest(app)
            %clear sound;
            for i = 1:9
                ff = app.f_audiogram(i) % czestotliwosc generowanego dzwieku [Hz]
                tt = 0:1/app.fs:1;

                while app.ii < 9 % powinno byc 14
                    app.right_ear(i) = (app.ii-1)*10; % ubytek w dB
                    
                    %             gain = 0; % wzmocnienie dB; Do wzmacniania kolejnych
                    coeff = app.coef_audiogram(app.ii) % wspolczynnik (zwiekszajacy +dB)
                    app.y=(coeff*(app.A)*(sin(2*pi*ff*tt)));  % sygnal harmoniczny
                    right=app.y(1,1:48000);
                    noise = app.A.*rand(1, 48000); % Czy tego szumu nie przyciszyc?                    
                    right = [noise; right];

                    % generowanie 10 sekund w lewym kanale
                    right_10sec = [];
                    for k = 1:2
                        right_10sec = [right_10sec right];
                    end

                    % sound(right_10sec, app.fs);

                    q = audioplayer(right_10sec, app.fs);
                    playblocking(q, app.fs);

                    app.ii = app.ii + 1;

                    
                end

                app.ii = 1;

                %                 q = audioplayer(right_10sec, app.fs);
                %                 playblocking(q, app.fs);

            end
            % wynik audiogramu w lewym uchu [dB]
            % 500 Hz - element #2, 1000 Hz - #3, 2000 Hz - #4, 4000 Hz - #6
            if (abs(app.right_ear(2) - app.right_ear(4)) > 40)
                if (app.right_ear(4) < app.right_ear(6)) % Wyjatek nr 2
                    app.right_ear_score = (app.right_ear(2) + app.right_ear(3) + app.right_ear(4) + app.right_ear(6)) / 4.0;
                else % Wyjatek nr 1
                    app.right_ear_score = (app.right_ear(2) + app.right_ear(3) + app.right_ear(6)) / 3.0;
                end
            else % Przypadek typowy
                app.right_ear_score = (app.right_ear(2) + app.right_ear(3) + app.right_ear(4)) / 3.0
            end
            
            p = plot(app.AxesAudiogram, app.f_audiogram, app.right_ear);
            p.Color = 'red';
            hold(app.AxesAudiogram, 'on');
            app.EditFieldRightEar.Value = app.right_ear_score;
            
            app.score;
        end


    end % method
    
    methods (Access = private)
        
        % 0 - lewy kanal, 1 - prawy kanal
        function [] = calibration(app, channel)
            
            app.fs=48000; % f probkowania
            app.f=1000; % f generowanego dzwieku
            app.t=0:1/app.fs:1; % probki czasowe
            app.A=0.01; % domyslnie bylo 1
            app.y=app.A*sin(2*pi*app.f*app.t);  % sygnal harmoniczny
            Py=10*log10(sum(app.y.^2)); % moc akustyczna
            coef=10.^(5/20); % wspolczynnik (zwiekszajacy +dB)
            yy=app.y.*coef; % wzmocnienie sygnalu o +5dB
            Pyy=10*log10(sum(yy.^2)); % moc akustyczna +5dB
            test=[]; % dzwiek testowy do kalibracji
            
            % generowanie 2*10 sekund sygnalu testowego do kalibracji
            if (channel == 0) % kanal lewy
                left=[app.y(1,1:48000) yy(1,1:48000)];
                noise = app.A.*rand(1, 96000); % Czy tego szumu nie przyciszyc?
                left = [left; noise];
                for i=1:10
                    test=[test left];
                end
            else % kanal prawy
                right=[app.y(1,1:48000) yy(1,1:48000)];
                noise = app.A.*rand(1, 96000);
                right = [noise; right];
                for i=1:10
                    test=[test right];
                end
            end


            sound(test, app.fs); %dzwiek testowy
            h=questdlg('Kliknij, gdy będziesz słyszeć tylko jeden z dwóch tonów', ...
                'Kalibracja','OK','OK');
            switch h
                case 'OK'
                    %'OK' code here
                    clear("sound");
                case ''
                    %abort code
                otherwise
            end
        end
        
        function [] = score(app)
            
            % Wynik finalny audiogramu
            if (abs(app.right_ear_score - app.left_ear_score) > 25)
               app.final_score = 5;
            end
            if (app.right_ear_score < app.left_ear_score)
                app.final_score = app.final_score + app.right_ear_score;
            else
                app.final_score = app.final_score + app.left_ear_score;
            end
            app.EditFieldScore.Value = app.final_score;
            
            % Okienko z informacja
            if (app.final_score > 120)
                text = ('Calkowita gluchota');
            elseif (app.final_score > 90)
                text = ('Glebokie uszkodzenie sluchu');
            elseif (app.final_score > 70)
                text = ('Znaczne uszkodzenie sluchu'); 
            elseif (app.final_score > 40)
                text = ('Umiarkowane uszkodzenie sluchu');
            elseif (app.final_score > 20)
                text = ('Lekkie uszkodzenie sluchu');
            else
                text = ('Sluch w normie');                            
            end
            
            h=questdlg(text, 'Wynik audiogramu', 'OK', 'OK');
            switch h
                case 'OK'
                    %'OK' code here
                case ''
                    %abort code
                otherwise
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Wypelnienie tablicy wspolczynnikow wzmocnienia
            for i = 1:14 %wypelnienie
                app.coef_audiogram(i+1) = 10.^(i*10/20);
            end

            % Uniwersalny generator
            app.f = app.f_audiogram(1); % czestotliwosc generowanego dzwieku [Hz]
            app.t = 0:1/app.fs:1;
            %             gain = 0; % wzmocnienie dB; Do wzmacniania kolejnych
            coef = app.coef_audiogram(1); % wspolczynnik (zwiekszajacy +dB)
            app.y=coef*app.A*sin(2*pi*app.f*app.t);  % sygnal harmoniczny


            app.calibration(0);
            
            % Lewy kanal
%             left=app.y(1,1:48000);
%             left = [left; zeros(size(left))];
%             % generowanie 10 sekund w lewym kanale
%             left_10sec = [];
%             for i = 1:10
%                 left_10sec = [left_10sec left];
%             end
%             sound(left_10sec, app.fs);

            %             % Prawy kanal
            %             right=y(1,1:48000);
            %             right = [zeros(size(right)); right];
            %             % generowanie 10 sekund w prawym kanale
            %             right_10sec = []
            %             for i = 1:10
            %                 right_10sec = [right_10sec right];
            %             end
            %             sound(right_10sec, fs);


            % NAPISZ KALIBRACJE OD NOWA NP. Z UZYCIEM GENERATORA
            %kalibracja
            

            %             f = msgbox("Witam", "Kalibracja");
            %             while (f == true)
            %                 sound(zz, fs);
            %             end
            %f = msgbox("Witam", "Kalibracja");
        end

        % Button pushed function: ButtonRozpocznij
        function CallbackRozpocznij(app, event)

            % Procedura wykonania audiogramu - DOPISAC

            % Kanał lewy - wykorzystac generator i 2 petle (czestotliwosci i wzmocnienia)

            app.leftTest;
            app.calibration(1);
            app.rightTest;
          

            %             for i = 1:9
            %                 for j = 1:14
            %                     app.y=app.coef_audiogram(j)*app.A*sin(2*pi*app.f_audiogram(i)*app.t);  % sygnal harmoniczny
            %                     left=app.y(1,1:48000);
            %                     left = [left; zeros(size(left))];
            %                     % generowanie 10 sekund w lewym kanale
            %                     left_10sec = [];
            %                     for k = 1:5
            %                         left_10sec = [left_10sec left];
            %                     end
            %                     q = audioplayer(left_10sec, app.fs);
            %                     playblocking(q, app.fs);
            %                 end
            %             end

            % Kanal prawy - wykorzystac generator i 2 petle (czestotliwosci i wzmocnienia

            %        app.rightTest;

            %  for i = 1:9
            %     for j = 1:14
            %       app.y=app.coef_audiogram(j)*app.A*sin(2*pi*app.f_audiogram(i)*app.t);  % sygnal harmoniczny
            %        right=app.y(1,1:48000);
            %       right = [zeros(size(right)); right];
            % generowanie 10 sekund w prawym kanale
            %       right_10sec = [];
            %        for n = 1:10
            %          right_10sec = [right_10sec right];
            %      end
            %        sound(right_10sec, app.fs);
            %     end
            %  end

        end

        % Button pushed function: ButtonSlysze
        function CallbackSlysze(app, event)
            app.ii = 9; % zatrzymanie petli dla glosnosci
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudiogramApp = uifigure('Visible', 'off');
            app.AudiogramApp.Name = 'MATLAB App';
            app.AxesAudiogram = uiaxes(app.AudiogramApp);
            title(app.AxesAudiogram, 'Audiogram')
            xlabel(app.AxesAudiogram, 'Częstotliwość [Hz]')
            ylabel(app.AxesAudiogram, 'Ubytek słuchu [dB]')
            zlabel(app.AxesAudiogram, 'Z')
            app.AxesAudiogram.XLim = [0 10000];
            app.AxesAudiogram.YLim = [0 140];
            app.AxesAudiogram.YDir = 'reverse';
            app.AxesAudiogram.XAxisLocation = 'top';
            app.AxesAudiogram.XTick = [0 125 500 1000 2000 3000 4000 6000 8000 10000];
            app.AxesAudiogram.XTickLabel = {'0'; '125'; '500'; '1000'; '2000'; '3000'; '4000'; '6000'; '8000'; '10000'};
            app.AxesAudiogram.YTick = [0 20 40 60 80 100 120 140];
            app.AxesAudiogram.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'};
            app.ImageCelebrity = uiimage(app.AudiogramApp);
            app.ButtonNext = uibutton(app.AudiogramApp, 'push');
            app.ButtonPrevious = uibutton(app.AudiogramApp, 'push');
            app.ButtonPorownaj = uibutton(app.AudiogramApp, 'push');
            app.ButtonPorownaj.Text = 'Porównaj';
            app.ButtonSlysze = uibutton(app.AudiogramApp, 'push');
            app.ButtonSlysze.ButtonPushedFcn = createCallbackFcn(app, @CallbackSlysze, true);
            app.ButtonSlysze.Text = 'Słyszę';
            app.ButtonRozpocznij = uibutton(app.AudiogramApp, 'push');
            app.ButtonRozpocznij.ButtonPushedFcn = createCallbackFcn(app, @CallbackRozpocznij, true);
            app.ButtonRozpocznij.Text = 'Rozpocznij test';
            app.LeweuchodBLabel = uilabel(app.AudiogramApp);
            app.LeweuchodBLabel.Text = 'Lewe ucho [dB]';
            app.EditFieldLeftEar = uieditfield(app.AudiogramApp, 'numeric');
            app.EditFieldLeftEar.Editable = 'off';
            app.PraweuchodBLabel = uilabel(app.AudiogramApp);
            app.PraweuchodBLabel.Text = 'Prawe ucho [dB]';
            app.EditFieldRightEar = uieditfield(app.AudiogramApp, 'numeric');
            app.EditFieldRightEar.Editable = 'off';
            app.WynikdBLabel = uilabel(app.AudiogramApp);
            app.WynikdBLabel.Text = 'Wynik [dB]';
            app.EditFieldScore = uieditfield(app.AudiogramApp, 'numeric');
            app.EditFieldScore.Editable = 'off';
            % Show the figure after all components are created
            app.AudiogramApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audiogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudiogramApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudiogramApp)
        end
    end
end